classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LEditField             matlab.ui.control.NumericEditField
        LEditFieldLabel        matlab.ui.control.Label
        REditField             matlab.ui.control.NumericEditField
        REditField_2Label      matlab.ui.control.Label
        ofRunEditField         matlab.ui.control.NumericEditField
        ofRunEditFieldLabel    matlab.ui.control.Label
        oftrialEditField       matlab.ui.control.NumericEditField
        oftrialEditFieldLabel  matlab.ui.control.Label
        LabelEditField         matlab.ui.control.EditField
        LabelEditFieldLabel    matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        Image                  matlab.ui.control.Image
    end


    properties (Access = private)
        img_cross = imread('C:\Users\Peter\Desktop\CGX_git\img\cross.png');
        img_right = imread('C:\Users\Peter\Desktop\CGX_git\img\right.png');
        img_left  = imread('C:\Users\Peter\Desktop\CGX_git\img\left.png');
        img_black = imread('C:\Users\Peter\Desktop\CGX_git\img\black.png');
        img_end   = imread('C:\Users\Peter\Desktop\CGX_git\img\end.png');
        mouse
    end
    
    methods (Access = public)
        
        function results = push_marker_1(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 290);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
            
        end
        
        function results = push_marker_2(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 317);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
          
        end
        function results = push_marker_3(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 345);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
          
        end

        function results = Record_start(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;

            app.mouse.mouseMove(1827, 60);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.mouse.mouseMove(1770, 240);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.push_marker_3();
          
        end

        function results = Record_stop(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1655, 58);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.mouse.mouseMove(1710, 360);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.Image.ImageSource = app.img_right;
          
        end
        
        function results = Trial_right(app)
            app.oftrialEditField.Value = app.oftrialEditField.Value + 1;
            app.REditField.Value = app.REditField.Value + 1; 
            app.Image.ImageSource = app.img_cross; app.LabelEditField.Value = "Right";
            beep;
            pause(2)
            app.Image.ImageSource = app.img_right;  app.push_marker_1();
            pause(1.25)
            app.Image.ImageSource = app.img_cross; 
            pause(3)
            app.Image.ImageSource = app.img_black; 
            pause(2)
        end


        function results = Trial_left(app)
            app.oftrialEditField.Value = app.oftrialEditField.Value + 1;
            app.LEditField.Value = app.LEditField.Value + 1;
            app.Image.ImageSource = app.img_cross; app.LabelEditField.Value = "Left";
            beep;
            pause(2)
            app.Image.ImageSource = app.img_left;  app.push_marker_2();
            pause(1.25)
            app.Image.ImageSource = app.img_cross;  
            pause(3)
            app.Image.ImageSource = app.img_black; 
            pause(2)
        end
        
        function results = One_run(app)
            app.ofRunEditField.Value = app.ofRunEditField.Value + 1;
            tmp = randperm(24);

            idx_even = find(mod(tmp,2)==0);
            idx_odd = find(mod(tmp,2)~=0);
            tmp(idx_odd) = 1
            tmp(idx_even) = 2

            for j = 1:length(tmp)
                if tmp(j) == 1
                    app.Trial_right();
                else
                    app.Trial_left();
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oftrialEditField.Value = 0;
            app.REditField.Value = 0;
            app.LEditField.Value = 0;
            app.ofRunEditField.Value = 0;
        end

        % Button pushed function: StartButton
        function Push_start_bttn(app, event)
            app.Record_start(); pause(2)
            
            
            app.One_run();
            app.One_run();
            app.One_run();
            app.One_run();
            app.One_run();
            app.One_run();

            app.Record_stop();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'start.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Push_start_bttn, true);
            app.StartButton.Text = 'Start';
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.oftrialEditFieldLabel = uilabel(app.UIFigure);
            app.oftrialEditFieldLabel.Text = '# of trial';
            app.oftrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofRunEditFieldLabel = uilabel(app.UIFigure);
            app.ofRunEditFieldLabel.Text = '# of Run';
            app.ofRunEditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField_2Label = uilabel(app.UIFigure);
            app.REditField_2Label.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end